classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoAEstimationUIFigure           matlab.ui.Figure
        change                          matlab.ui.control.StateButton
        ThefirstsourcewithMUSICisLabel  matlab.ui.control.Label
        ThesecondsourcewithMUSICisLabel  matlab.ui.control.Label
        an2                             matlab.ui.control.Label
        an1                             matlab.ui.control.Label
    end

    properties (Access = private)
        Pax % Polar axes
        NOURCE = 2
        samplingTime = 0.5 % seconds
        devReader % which was what you think
        calcRate
        fs = 16000
        period = 0.1 % the tic time.
        err = 0 % if there's a problem.
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % may be this can work.
            % OK. Matlab I love you again.
            
            % polar axes.
            app.Pax = polaraxes(app.DoAEstimationUIFigure);
            app.Pax.ThetaLim = [-90,90];
            app.Pax.ThetaZeroLocation = 'top';
            app.Pax.Units = 'pixels';
            app.Pax.Position = [30 10 app.DoAEstimationUIFigure.Position(3)-60 app.DoAEstimationUIFigure.Position(4)-50];
            
            % Timer
            
            % fixedRate may cause serious problem
            % such as when last plot wasn't ended, next begins.
            % but fixedSpacing may not perfect. Discussion is still doing...


            app.calcRate = 1/app.samplingTime;
            % audio deviece can be set here.
            try
                app.devReader = audioDeviceReader( ...
                    'Driver', 'DirectSound', ...
                    'SamplesPerFrame', app.fs*(app.samplingTime + app.period), ...
                    'SampleRate', app.fs, ...
                    'NumChannels', 4, ...
                    'BitDepth', '16-bit integer', ...
                    'Device', '麦克风 (USB YDB01 Audio Effect)', ...
                    'ChannelMappingSource', 'Property', ...
                    'ChannelMapping', [2 1 4 3] ...
                    );
            catch ME
                if(strcmp(ME.identifier,"MATLAB:system:StringSet:InvalidValue"))
                    app.change.Text = "Retry";
                    app.err = 1;
                    errordlg("Please connect microphone.","No Micophone");
                    
                end
            end
        end

        % Value changed function: change
        function changeValueChanged(app, event)
            % run prepare part
            if(app.change.Value == 1 )
                if(app.err == 1)
                    try
                        app.devReader = audioDeviceReader( ...
                            'Driver', 'DirectSound', ...
                            'SamplesPerFrame', app.fs*(app.samplingTime + app.pierid), ...
                            'SampleRate', app.fs, ...
                            'NumChannels', 4, ...
                            'BitDepth', '16-bit integer', ...
                            'Device', '麦克风 (USB YDB01 Audio Effect)', ...
                            'ChannelMappingSource', 'Property', ...
                            'ChannelMapping', [2 1 4 3] ...
                            );
                    catch ME
                        if(strcmp(ME.identifier,"MATLAB:system:StringSet:InvalidValue"))
                            app.change.Text = "Retry";
                            app.err = 1;
                            app.change.Value = 0;
                            errordlg("Please connect microphone.","No Micophone");
                            
                            return;
                        end
                    end
                end
                % ensure there's not problems.
                % OK now I think it's OK.
                try
                    setup(app.devReader); % where to setup and release is still a problem.
                catch ME
                    if(strcmp(ME.identifier,"MATLAB:UndefinedFunction"))
                        errordlg("Please connect microphone.","No Micophone");
                        app.change.Text = "Retry";
                        app.err = 1;
                        app.change.Value = 0;
                        
                        return;
                    end
                end
                
                % do necessary work and start the timer.
                app.change.Text = 'Stop';
                disp(['Calculation rate is: ', num2str(app.calcRate)]);
                disp("Start collecting...");
            end
            
            
            while(app.change.Value == 1)
                % run work part
                data = app.devReader();
                data = data(1: app.fs*app.samplingTime, 1: 4);
                X = hilbert(data);
                
                [Frame, ~] = size(X);
                len = 2048;
                inc = 512;
                nfft = len; % The smallest 2^n \ge len, to optimize FFT
                [st_idx, ed_idx, fn] = separate(len, inc, Frame);
                STFT = zeros([fn nfft 4]);
                for i=1:fn
                    STFT(i, :, :) = fft(X(st_idx(i):ed_idx(i), :), nfft);
                end
                
                % Perform MUSIC algorithm
                
                % Initialize data
                J = 4;
                dx = 2.5*10^-2;
                dy = 0;
                c = 340; % Velocity of sound
                Index = linspace(0,J-app.NOURCE,J);
                p = (-(J-1)/2 + Index.') * [dx dy]; % Position vector
                stride = 0.5;
                theta = -90:stride:90;
                v = [sin(theta*pi/180); -cos(theta*pi/180)];
                
                P = zeros([180/stride+1 1]); % -90:stride:90
                
                fr = [40 3000]*nfft/app.fs+1; % range of frequency (to add weight)
                
                for i=floor(fr(1)):ceil(fr(2))
                    
                    % P: index -> f
                    % $$\frac{(k - 1)f_s}{n}$$
                    f_c = (i - 1)*app.fs/nfft;
                    X_ = squeeze(STFT(:, i, :));
                    
                    [Frame_, ~] = size(X_);
                    
                    R_x = X_'*X_/Frame_;
                    a_theta = exp(-1i*2*pi*f_c*(p*v)./c); % steering vector
                    
                    [V, D] = eig(R_x);
                    eig_val = diag(D);
                    [~, Idx] = sort(eig_val);
                    Un = V(:, Idx(1:J-app.NOURCE)); % noise subspace
                    P_sm = diag(a_theta'*(Un*Un')*a_theta);
                    P = P + P_sm;
                    
                end
                
                P = 1./P;
                % plot the graph.
                
                step = 180/(length(P)-1);
                angle = -90:step:90;
                theta_radians = deg2rad(angle);
                polarplot(app.Pax, theta_radians, abs(P));
                app.Pax.ThetaLim = [-90,90];
                app.Pax.ThetaZeroLocation = 'top';
                app.Pax.Units = 'pixels';
                app.Pax.RTick = [0 0.02 0.04 0.06 0.08 0.1];
                app.Pax.RLim = [0 0.1];
                app.Pax.Position = [30 10 app.DoAEstimationUIFigure.Position(3)-60 app.DoAEstimationUIFigure.Position(4)-50];
                
                
                % Find the local maximum;
                P_middle = abs(P(2:end-1));
                P_front = abs(P(1:end-2));
                P_back = abs(P(3:end));
                logic_front = (P_middle - P_front)>0;
                logic_back = (P_middle - P_back)>0;
                logic = logic_front & logic_back;
                P_middle(~logic) = min(P_middle);
                P_local = [abs(P(1));P_middle;abs(P(end))];
                [~,doa_Idx] = maxk(P_local, 2);
                doa = theta(doa_Idx);
                [~,minIdx] = min(abs(doa));
                source_1 = doa(minIdx);
                [~,maxIdx] = max(abs(doa));
                source_2 = doa(maxIdx);
                
                
                disp(['The first source with MUSIC is: ',num2str(source_1),' deg']);
                disp(['The second source with MUSIC is: ',num2str(source_2),' deg']);
                app.an1.Text = num2str(source_1);
                app.an2.Text = num2str(source_2);
                
                pause(app.period);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                drawnow; % woc!!!!!  nb!!!!!! I announce that this command is my father!!!!!!!
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            end
            
            release(app.devReader);
            app.change.Text = 'Continue';


            function [ st_index, ed_index, fn ] = separate(len, inc, Frame)
                fn = floor((Frame-len)/inc + 1);
                st_index = (0:(fn-1))*inc + 1;
                ed_index = (0:(fn-1))*inc + len;
            end
            
            
        end

        % Close request function: DoAEstimationUIFigure
        function DoAEstimationUIFigureCloseRequest(app, event)
            delete(app);
            clear all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DoAEstimationUIFigure = uifigure('Visible', 'off');
            app.DoAEstimationUIFigure.Name = 'DoA-Estimation';
            app.DoAEstimationUIFigure.CloseRequestFcn = createCallbackFcn(app, @DoAEstimationUIFigureCloseRequest, true);
            app.change = uibutton(app.DoAEstimationUIFigure, 'state');
            app.change.ValueChangedFcn = createCallbackFcn(app, @changeValueChanged, true);
            app.change.Text = 'Start';
            app.ThefirstsourcewithMUSICisLabel = uilabel(app.DoAEstimationUIFigure);
            app.ThefirstsourcewithMUSICisLabel.Text = 'The first source with MUSIC is:';
            app.ThesecondsourcewithMUSICisLabel = uilabel(app.DoAEstimationUIFigure);
            app.ThesecondsourcewithMUSICisLabel.Text = 'The second source with MUSIC is:';
            app.an2 = uilabel(app.DoAEstimationUIFigure);
            app.an2.Text = 'UKN';
            app.an1 = uilabel(app.DoAEstimationUIFigure);
            app.an1.Text = 'UKN';
            % Show the figure after all components are created
            app.DoAEstimationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DoAEstimationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoAEstimationUIFigure)
        end
    end
end